classdef BehaviorDEPOT_GUI_v1_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BehaviorDEPOTv16Label          matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
        TrackingFileTypeButton         matlab.ui.control.DropDown
        TrackingFileTypeLabel          matlab.ui.control.Label
        UseGPUSwitch                   matlab.ui.control.Switch
        UseGPUSwitchLabel              matlab.ui.control.Label
        ParallelProcessingLabel        matlab.ui.control.Label
        BehaviorAnalysisCheckBox       matlab.ui.control.CheckBox
        ModulestoRunLabel              matlab.ui.control.Label
        DataPrepCheckBox               matlab.ui.control.CheckBox
        HampelspanEditField            matlab.ui.control.NumericEditField
        HampelspanEditFieldLabel       matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DataPrepOptionsLabel           matlab.ui.control.Label
        StartDataAnalysisLabel         matlab.ui.control.Label
        HelperFunctionsPanel           matlab.ui.container.Panel
        OpenClassifierFolderButton     matlab.ui.control.Button
        ConvertHumanAnnotationsButton  matlab.ui.control.Button
        SaveSettingsButton             matlab.ui.control.Button
        TemporalFilteringLabel         matlab.ui.control.Label
        ForframelockedeventsLabel      matlab.ui.control.Label
        SpatialFilteringLabel          matlab.ui.control.Label
        valuefrom01Label               matlab.ui.control.Label
        framesLabel                    matlab.ui.control.Label
        TrackingcutoffthresholdEditField  matlab.ui.control.NumericEditField
        TrackingcutoffthresholdEditFieldLabel  matlab.ui.control.Label
        ApplyHampelCorrectionCheckBox  matlab.ui.control.CheckBox
        SmoothingmethodDropDown        matlab.ui.control.DropDown
        SmoothingmethodDropDownLabel   matlab.ui.control.Label
        SmoothingspanEditField         matlab.ui.control.NumericEditField
        SmoothingspanEditFieldLabel    matlab.ui.control.Label
        ChooseClassifiersTable         matlab.ui.control.Table
        LoadSettingsStartButton        matlab.ui.control.Button
        AdditionalModulesPanel         matlab.ui.container.Panel
        ClassifierOptimizationButton   matlab.ui.control.Button
        DataExplorationButton          matlab.ui.control.Button
        ClassifierValidationButton     matlab.ui.control.Button
        InterRaterButton               matlab.ui.control.Button
        ROISLabel                      matlab.ui.control.Label
        ROIEditField                   matlab.ui.control.NumericEditField
        pixels2cmEditField             matlab.ui.control.NumericEditField
        DistancecalibrationLabel       matlab.ui.control.Label
        pixelspercmLabel               matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        DataPrepAnalysisModulesLabel   matlab.ui.control.Label
        DrawROIsforfilteringLabel      matlab.ui.control.Label
        ApplytemporalfiltersCheckBox   matlab.ui.control.CheckBox
        PlotDisplayOptionsLabel        matlab.ui.control.Label
        BehaviorlocationsCheckBox      matlab.ui.control.CheckBox
        SpatialtrajectoryCheckBox      matlab.ui.control.CheckBox
        SpatiotemporaltrajectoryCheckBox  matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        BatchButton                    matlab.ui.control.RadioButton
        SingleButton                   matlab.ui.control.RadioButton
    end

   
    properties (Access = private)
        P = struct; % Struct to hold parameter settings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startDir = pwd;
            guiPath = mfilename("fullpath");
            [appPath, ~, ~] = fileparts(guiPath);
            app.P.guiPath = appPath;
            
            if ispc
                classifierPath = [appPath '\code\classifiers\'];
                codePath = [appPath '\code\'];
            else
                classifierPath = [appPath '/code/classifiers/'];
                codePath = [appPath '/code/'];
            end

            % Set classifierPath and codePath variables
            classifierPath = fullfile(appPath, 'code', 'classifiers');
            codePath = fullfile(appPath, 'code');
            
            addpath(genpath(codePath));
            app.P.codePath = codePath;
            app.P.classifierPath = classifierPath;
            cd(classifierPath)
            class_search = dir('*.m'); % Collect list of classifiers in app/code/classifiers folder
            classifier_names = {};
            param_names = {};
            
            c = 1;
            d = 1;
            
            for i = 1:size(class_search, 1)
                [~, name, ~] = fileparts(class_search(i).name);
                
                if ~startsWith(name, 'P_')
                    % Collect classifier names from class_search       
                    classifier_names{c} = name;
                    c = c + 1;
                else
                    % Collect param file names to match to classifiers
                    param_names{d} = name;
                    d = d + 1;
                end
            end
            
            classifier_names = classifier_names';
            param_names = param_names';
            
            % Match param files to classifier files
            available_classifiers = {};

            for i = 1:size(param_names, 1)
                
                file_match = endsWith(param_names(i), classifier_names);
                
                if file_match
                    name_edit = param_names{i};
                    name_edit = name_edit(3:end);
                    class_idx = contains(classifier_names, name_edit);
                    class_file = classifier_names{class_idx};
                    if size(available_classifiers, 1) == 0
                        available_classifiers{1} = class_file;
                        param_files{1} = param_names{i};
                    else
                        available_classifiers = [available_classifiers; class_file];
                        param_files = [param_files; param_names{i}];
                    end
                end
            end
            
            table_columns = false(size(available_classifiers));
            
            app.P.paramHandles = cellfun(@str2func, param_files, 'UniformOutput', false);
            app.P.classHandles = cellfun(@str2func, available_classifiers, 'UniformOutput', false);
            app.P.classSelect = false(size(available_classifiers));
            app.P.classEdit = false(size(available_classifiers));
            app.P.classifierNames = available_classifiers;
            app.P.paramFiles = param_files;
            
            app.ChooseClassifiersTable.Data = table(available_classifiers, table_columns, table_columns); % Assign classifier info to table
            
            app.P.tracking_type = app.TrackingFileTypeButton.Value;
            app.P.use_GPU = app.UseGPUSwitch.Value;

            app.P.smoothMethod = app.SmoothingmethodDropDown.Value;
            app.P.smoothSpan = app.SmoothingspanEditField.Value;
            app.P.cutoffThreshold = 0.1;
            app.P.hampel = true;
            app.P.hampel_span = 7;
            app.P.do_ROI = false;
            app.P.number_ROIs = 0;
            app.P.do_events = false;
            app.P.viewSpatialTrajectory = false;
            app.P.viewSpatioTemporalTrajectory = false;
            app.P.viewBehaviorLocations = false;
            app.P.px2cm = 32;  % pixels per centimeter
            app.P.batchSession = 0;  % true if batch process; false if not
            app.P.dataPrep = 1;
            app.P.beh_analysis = 1;
            
            if exist([app.P.codePath, addSlash(), 'gui_settings.mat'], 'file') == 2
            
                % Load settings file
                load([app.P.codePath, addSlash(), 'gui_settings.mat']);
                
                % Set loaded parameters as current GUI state
                app.TrackingFileTypeButton.Value = save_state.TrackingFileTypeButton.Value;
                app.UseGPUSwitch.Value = save_state.UseGPUSwitch.Value; 
                app.BehaviorAnalysisCheckBox.Value = save_state.BehaviorAnalysisCheckBox.Value;
                app.DataPrepCheckBox.Value = save_state.DataPrepCheckBox.Value;
                app.HampelspanEditField.Value = save_state.HampelspanEditField.Value;
                app.TrackingcutoffthresholdEditField.Value = save_state.TrackingcutoffthresholdEditField.Value;
                app.ApplyHampelCorrectionCheckBox.Value = save_state.ApplyHampelCorrectionCheckBox.Value;
                app.SmoothingmethodDropDown.Value = save_state.SmoothingmethodDropDown.Value;
                app.SmoothingspanEditField.Value = save_state.SmoothingspanEditField.Value;
                app.ROIEditField.Value = save_state.ROIEditField.Value;
                app.pixels2cmEditField.Value = save_state.pixels2cmEditField.Value;
                app.ApplytemporalfiltersCheckBox.Value = save_state.ApplytemporalfiltersCheckBox.Value;
                app.BehaviorlocationsCheckBox.Value = save_state.BehaviorlocationsCheckBox.Value;
                app.SpatialtrajectoryCheckBox.Value = save_state.SpatialtrajectoryCheckBox.Value;
                app.SpatiotemporaltrajectoryCheckBox.Value = save_state.SpatiotemporaltrajectoryCheckBox.Value;
                app.BatchButton.Value = save_state.BatchButton.Value;
                app.SingleButton.Value = save_state.SingleButton.Value;

                % Set loaded parameters within P structure
                app.P.tracking_type = save_state.TrackingFileTypeButton.Value;
                app.P.use_GPU = save_state.UseGPUSwitch.Value; 
                app.P.beh_analysis = save_state.BehaviorAnalysisCheckBox.Value;
                app.P.dataPrep = save_state.DataPrepCheckBox.Value;
                app.P.hampel_span = save_state.HampelspanEditField.Value;
                app.P.cutoffThreshold = save_state.TrackingcutoffthresholdEditField.Value;
                app.P.hampel = save_state.ApplyHampelCorrectionCheckBox.Value;
                app.P.smoothMethod = save_state.SmoothingmethodDropDown.Value;
                app.P.smoothSpan = save_state.SmoothingspanEditField.Value;
                app.P.number_ROIs = save_state.ROIEditField.Value;
                app.P.px2cm = save_state.pixels2cmEditField.Value;
                app.P.do_events = save_state.ApplytemporalfiltersCheckBox.Value;
                app.P.viewBehaviorLocations = save_state.BehaviorlocationsCheckBox.Value;
                app.P.viewSpatialTrajectory = save_state.SpatialtrajectoryCheckBox.Value;
                app.P.viewSpatioTemporalTrajectory = save_state.SpatiotemporaltrajectoryCheckBox.Value;
                app.P.batchSession = save_state.BatchButton.Value;
                app.P.do_ROI = save_state.do_ROI;

                clearvars save_state
            end

            cd(startDir)
        end

        % Value changed function: ApplytemporalfiltersCheckBox
        function ApplytemporalfiltersCheckBoxValueChanged(app, event)
            value = app.ApplytemporalfiltersCheckBox.Value;
            app.P.do_events = value;
        end

        % Value changed function: SpatiotemporaltrajectoryCheckBox
        function SpatiotemporaltrajectoryCheckBoxValueChanged(app, event)
            value = app.SpatiotemporaltrajectoryCheckBox.Value;
            app.P.viewSpatioTemporalTrajectory = value;
        end

        % Value changed function: SpatialtrajectoryCheckBox
        function SpatialtrajectoryCheckBoxValueChanged(app, event)
            value = app.SpatialtrajectoryCheckBox.Value;
            app.P.viewSpatialTrajectory = value;
        end

        % Value changed function: BehaviorlocationsCheckBox
        function BehaviorlocationsCheckBoxValueChanged(app, event)
            value = app.BehaviorlocationsCheckBox.Value;
            app.P.viewBehaviorLocations = value;
        end

        % Value changed function: pixels2cmEditField
        function pixels2cmEditFieldValueChanged(app, event)
            value = app.pixels2cmEditField.Value;
            app.P.px2cm = round(value);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.BatchButton.Value == 1
                app.P.batchSession = 1;
            else
                app.P.batchSession = 0;
            end
        end

        % Value changed function: ROIEditField
        function ROIEditFieldValueChanged(app, event)
            value = app.ROIEditField.Value;
            if value == 0
                app.P.do_ROI = false;
                app.P.number_ROIs = 0;
            else
                app.P.do_ROI = true;
                app.P.number_ROIs = value;
                if app.P.number_ROIs == 0
                    app.P.do_ROI = false;
                end
            end
        end

        % Button pushed function: InterRaterButton
        function InterRaterButtonPushed(app, event)
            launch_IR_module();
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            gui_settings = app.P;
            if ~ispc
                menu('Select directory to save file', 'OK');
            end
            
            savedir = uigetdir('','Select directory to save file');
            save('BehDepot_settings.mat', "gui_settings");
            msgbox(['Settings saved in ' savedir]);
        end

        % Button pushed function: LoadSettingsStartButton
        function LoadSettingsStartButtonPushed(app, event)
            if ~ispc
                menu('Select BehaviorDEPOT settings file', 'OK');
            end
            
            [f, p] = uigetfile('Select BehaviorDEPOT settings file');
            tmp = load([p f]);
            app.P = tmp.gui_settings;
            
            BehDepo_mainscript(app.P)

        end

        % Button pushed function: ClassifierValidationButton
        function ClassifierValidationButtonPushed(app, event)
            validation_module();
        end

        % Button pushed function: DataExplorationButton
        function DataExplorationButtonPushed(app, event)
            data_exploration_module()
        end

        % Button pushed function: ClassifierOptimizationButton
        function ClassifierOptimizationButtonPushed(app, event)
            classifier_optimization_module()
        end

        % Button pushed function: ConvertHumanAnnotationsButton
        function ConvertHumanAnnotationsButtonPushed(app, event)
            convertHumanAnnotations_BD()
        end

        % Cell edit callback: ChooseClassifiersTable
        function ChooseClassifiersTableCellEdit(app, event)
            indices = event.Indices;
            
            % If changing params
            if indices(2) == 2
                newData = event.NewData;
                app.P.classEdit(indices(1)) = newData;
                
            % if selecting a classifier
            elseif indices(2) == 3
                newData = event.NewData;
                app.P.classSelect(indices(1)) = newData;
            end
        end

        % Value changed function: SmoothingmethodDropDown
        function SmoothingmethodDropDownValueChanged(app, event)
            smoothMethod = app.SmoothingmethodDropDown.Value;
            app.P.smoothMethod = smoothMethod;
        end

        % Value changed function: SmoothingspanEditField
        function SmoothingspanEditFieldValueChanged(app, event)
            smoothSpan = app.SmoothingspanEditField.Value;
            app.P.smoothSpan = smoothSpan;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            class_edit = app.P.classEdit;
            class_no_edit = xor(class_edit, app.P.classSelect);
            paramHandles = app.P.paramHandles;
            editParams = struct();
            tempParams = struct();
            behavior_names = cell(size(class_edit));
            
            for i = find(class_edit')
                to_edit = paramHandles{i};
                temp = to_edit();
                temp_field= fieldnames(temp);
                behavior_names(i) = temp_field;
                editParams.(temp_field{1}) = temp.(temp_field{1});
            end
            
            editBehavs = fieldnames(editParams);
            
            for i = 1:size(editBehavs, 1)
                
                edit_fields = fieldnames(editParams.(editBehavs{i}));
                this_behav = editParams.(editBehavs{i});
                default_vals = cell(size(edit_fields));

                for ii = 1:size(edit_fields, 1)
                    fields_to_edit = this_behav.(edit_fields{ii});
                    if isa(fields_to_edit, 'double')
                        default_vals{ii} = num2str(this_behav.(edit_fields{ii}));
                    elseif isa(fields_to_edit, 'cell')
                        default_vals{ii} = {fields_to_edit};
                    else
                        default_vals{ii} = fields_to_edit;
                    end
                end
                
                % Load parameter edit window for selected classifiers
                dims = [1 50];
                for ii = 1:size(edit_fields, 1)
                    if isa(default_vals{ii}, 'char')
                        param_edits{ii} = inputdlg(edit_fields(ii), editBehavs{i}, dims, default_vals(ii));
                    elseif isa(default_vals{ii}, 'cell')
                        vals_cell = default_vals{ii}{1};
                        edt_flds = repmat(edit_fields(ii), size(vals_cell));
                        param_edits{ii} = inputdlg(edt_flds, editBehavs{i}, dims, vals_cell)';
                    end
                end

                % Update parameter values for classifiers marked for edit    
                if ~isempty(param_edits)
                    for ii = 1:size(edit_fields, 1)
                        if size(param_edits{ii}, 2) == 1
                            editParams.(editBehavs{i}).(edit_fields{ii}) = str2num(param_edits{ii}{1});
                        elseif size(param_edits{ii}, 2) > 1
                            editParams.(editBehavs{i}).(edit_fields{ii}) = param_edits{ii};
                        end
                    end
                end
            end

            % Collect parameters from classifiers to run but not edited
            for i = find(class_no_edit')
                to_collect = paramHandles{i};
                temp = to_collect();
                temp_field= fieldnames(temp);
                behavior_names(i) = temp_field;
                tempParams.(temp_field{1}) = temp.(temp_field{1});
            end

            % Merge all final parameter values from tempParams and
            % editParams
            names = [fieldnames(tempParams); fieldnames(editParams)];
            app.P.Params = cell2struct([struct2cell(tempParams); struct2cell(editParams)], names, 1);           
            app.P.behavior_names = behavior_names;
            
            BehDEPOT_mainscript(app.P)
        end

        % Value changed function: TrackingcutoffthresholdEditField
        function TrackingcutoffthresholdEditFieldValueChanged(app, event)
            value = app.TrackingcutoffthresholdEditField.Value;
            app.P.cutoffThreshold = value;
        end

        % Value changed function: ApplyHampelCorrectionCheckBox
        function ApplyHampelCorrectionCheckBoxValueChanged(app, event)
            value = app.ApplyHampelCorrectionCheckBox.Value;
            app.P.hampel = value;
        end

        % Button pushed function: OpenClassifierFolderButton
        function OpenClassifierFolderButtonPushed(app, event)
            if ispc
                winopen(app.P.classifierPath);
            else
                try
                    open(app.P.classifierPath);
                catch
                    disp('Error opening classifier folder at:')
                    disp(app.P.classifierPath)
                end
            end
            disp(['Classifier Folder Path: ', app.P.classifierPath]);
        end

        % Value changed function: HampelspanEditField
        function HampelspanEditFieldValueChanged(app, event)
            hmpl_span = app.HampelspanEditField.Value;
            app.P.hampel_span = hmpl_span;
        end

        % Value changed function: DataPrepCheckBox
        function DataPrepCheckBoxValueChanged(app, event)
            value = app.DataPrepCheckBox.Value;
            app.P.dataPrep = value;
        end

        % Value changed function: BehaviorAnalysisCheckBox
        function BehaviorAnalysisCheckBoxValueChanged(app, event)
            value = app.BehaviorAnalysisCheckBox.Value;
            app.P.beh_analysis = value;
        end

        % Value changed function: UseGPUSwitch
        function UseGPUSwitchValueChanged(app, event)
            value = app.UseGPUSwitch.Value;
            app.P.use_GPU = value;
        end

        % Value changed function: TrackingFileTypeButton
        function TrackingFileTypeButtonValueChanged(app, event)
            value = app.TrackingFileTypeButton.Value;
            app.P.tracking_type = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Save app state
            save_state.TrackingFileTypeButton.Value = app.TrackingFileTypeButton.Value;
            save_state.UseGPUSwitch.Value = app.UseGPUSwitch.Value; 
            save_state.BehaviorAnalysisCheckBox.Value = app.BehaviorAnalysisCheckBox.Value;
            save_state.DataPrepCheckBox.Value = app.DataPrepCheckBox.Value;
            save_state.HampelspanEditField.Value = app.HampelspanEditField.Value;
            save_state.TrackingcutoffthresholdEditField.Value = app.TrackingcutoffthresholdEditField.Value;
            save_state.ApplyHampelCorrectionCheckBox.Value = app.ApplyHampelCorrectionCheckBox.Value;
            save_state.SmoothingmethodDropDown.Value = app.SmoothingmethodDropDown.Value;
            save_state.SmoothingspanEditField.Value = app.SmoothingspanEditField.Value;
            save_state.ROIEditField.Value = app.ROIEditField.Value;
            save_state.pixels2cmEditField.Value = app.pixels2cmEditField.Value;
            save_state.ApplytemporalfiltersCheckBox.Value = app.ApplytemporalfiltersCheckBox.Value;
            save_state.BehaviorlocationsCheckBox.Value = app.BehaviorlocationsCheckBox.Value;
            save_state.SpatialtrajectoryCheckBox.Value = app.SpatialtrajectoryCheckBox.Value;
            save_state.SpatiotemporaltrajectoryCheckBox.Value = app.SpatiotemporaltrajectoryCheckBox.Value;
            save_state.BatchButton.Value = app.BatchButton.Value;
            save_state.SingleButton.Value = app.SingleButton.Value;
            save_state.do_ROI = app.P.do_ROI;

            % save 'save_state' struct to file
            save([app.P.codePath, addSlash(), 'gui_settings.mat'], 'save_state')

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SingleButton = uiradiobutton(app.ButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.BatchButton = uiradiobutton(app.ButtonGroup);
            app.BatchButton.Text = 'Batch';
            app.SpatiotemporaltrajectoryCheckBox = uicheckbox(app.GridLayout);
            app.SpatiotemporaltrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatiotemporaltrajectoryCheckBoxValueChanged, true);
            app.SpatiotemporaltrajectoryCheckBox.Text = 'Spatiotemporal trajectory';
            app.SpatialtrajectoryCheckBox = uicheckbox(app.GridLayout);
            app.SpatialtrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatialtrajectoryCheckBoxValueChanged, true);
            app.SpatialtrajectoryCheckBox.Text = 'Spatial trajectory';
            app.BehaviorlocationsCheckBox = uicheckbox(app.GridLayout);
            app.BehaviorlocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BehaviorlocationsCheckBoxValueChanged, true);
            app.BehaviorlocationsCheckBox.Text = 'Behavior locations';
            app.PlotDisplayOptionsLabel = uilabel(app.GridLayout);
            app.PlotDisplayOptionsLabel.Text = 'Plot Display Options';
            app.ApplytemporalfiltersCheckBox = uicheckbox(app.GridLayout);
            app.ApplytemporalfiltersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplytemporalfiltersCheckBoxValueChanged, true);
            app.ApplytemporalfiltersCheckBox.Text = 'Apply temporal filter(s)?';
            app.DrawROIsforfilteringLabel = uilabel(app.GridLayout);
            app.DrawROIsforfilteringLabel.Text = '(Draw ROIs for filtering)';
            app.DataPrepAnalysisModulesLabel = uilabel(app.GridLayout);
            app.DataPrepAnalysisModulesLabel.Text = 'Data Prep & Analysis Modules';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.pixelspercmLabel = uilabel(app.GridLayout);
            app.pixelspercmLabel.Text = 'pixels per cm';
            app.DistancecalibrationLabel = uilabel(app.GridLayout);
            app.DistancecalibrationLabel.Text = 'Distance calibration:';
            app.pixels2cmEditField = uieditfield(app.GridLayout, 'numeric');
            app.pixels2cmEditField.ValueChangedFcn = createCallbackFcn(app, @pixels2cmEditFieldValueChanged, true);
            app.pixels2cmEditField.Value = 32;
            app.ROIEditField = uieditfield(app.GridLayout, 'numeric');
            app.ROIEditField.ValueChangedFcn = createCallbackFcn(app, @ROIEditFieldValueChanged, true);
            app.ROISLabel = uilabel(app.GridLayout);
            app.ROISLabel.Text = '# ROIS:';
            app.AdditionalModulesPanel = uipanel(app.GridLayout);
            app.AdditionalModulesPanel.Title = 'Additional Modules';
            app.InterRaterButton = uibutton(app.AdditionalModulesPanel, 'push');
            app.InterRaterButton.ButtonPushedFcn = createCallbackFcn(app, @InterRaterButtonPushed, true);
            app.InterRaterButton.Text = 'Inter-Rater';
            app.ClassifierValidationButton = uibutton(app.AdditionalModulesPanel, 'push');
            app.ClassifierValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifierValidationButtonPushed, true);
            app.ClassifierValidationButton.Text = 'Classifier Validation';
            app.DataExplorationButton = uibutton(app.AdditionalModulesPanel, 'push');
            app.DataExplorationButton.ButtonPushedFcn = createCallbackFcn(app, @DataExplorationButtonPushed, true);
            app.DataExplorationButton.Text = ' Data Exploration';
            app.ClassifierOptimizationButton = uibutton(app.AdditionalModulesPanel, 'push');
            app.ClassifierOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifierOptimizationButtonPushed, true);
            app.ClassifierOptimizationButton.Text = 'Classifier Optimization';
            app.LoadSettingsStartButton = uibutton(app.GridLayout, 'push');
            app.LoadSettingsStartButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsStartButtonPushed, true);
            app.LoadSettingsStartButton.Text = {'Load Settings'; '& Start'};
            app.ChooseClassifiersTable = uitable(app.GridLayout);
            app.ChooseClassifiersTable.ColumnName = {'Classifier Name'; 'Edit'; 'Run'};
            app.ChooseClassifiersTable.RowName = {};
            app.ChooseClassifiersTable.ColumnSortable = [true false false];
            app.ChooseClassifiersTable.ColumnEditable = [false true true];
            app.ChooseClassifiersTable.CellEditCallback = createCallbackFcn(app, @ChooseClassifiersTableCellEdit, true);
            app.SmoothingspanEditFieldLabel = uilabel(app.GridLayout);
            app.SmoothingspanEditFieldLabel.Text = 'Smoothing span:';
            app.SmoothingspanEditField = uieditfield(app.GridLayout, 'numeric');
            app.SmoothingspanEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingspanEditFieldValueChanged, true);
            app.SmoothingspanEditField.Value = 14;
            app.SmoothingmethodDropDownLabel = uilabel(app.GridLayout);
            app.SmoothingmethodDropDownLabel.Text = 'Smoothing method:';
            app.SmoothingmethodDropDown = uidropdown(app.GridLayout);
            app.SmoothingmethodDropDown.Items = {'lowess', 'moving', 'loess', 'rlowess', 'rloess'};
            app.SmoothingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingmethodDropDownValueChanged, true);
            app.SmoothingmethodDropDown.Value = 'lowess';
            app.ApplyHampelCorrectionCheckBox = uicheckbox(app.GridLayout);
            app.ApplyHampelCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyHampelCorrectionCheckBoxValueChanged, true);
            app.ApplyHampelCorrectionCheckBox.Text = 'Apply Hampel Correction';
            app.ApplyHampelCorrectionCheckBox.Value = true;
            app.TrackingcutoffthresholdEditFieldLabel = uilabel(app.GridLayout);
            app.TrackingcutoffthresholdEditFieldLabel.Text = 'Tracking cutoff threshold:';
            app.TrackingcutoffthresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.TrackingcutoffthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @TrackingcutoffthresholdEditFieldValueChanged, true);
            app.TrackingcutoffthresholdEditField.Value = 0.1;
            app.framesLabel = uilabel(app.GridLayout);
            app.framesLabel.Text = 'frames';
            app.valuefrom01Label = uilabel(app.GridLayout);
            app.valuefrom01Label.Text = '(value from 0-1)';
            app.SpatialFilteringLabel = uilabel(app.GridLayout);
            app.SpatialFilteringLabel.Text = 'Spatial Filtering';
            app.ForframelockedeventsLabel = uilabel(app.GridLayout);
            app.ForframelockedeventsLabel.Text = '(For frame-locked events)';
            app.TemporalFilteringLabel = uilabel(app.GridLayout);
            app.TemporalFilteringLabel.Text = 'Temporal Filtering';
            app.HelperFunctionsPanel = uipanel(app.GridLayout);
            app.HelperFunctionsPanel.Title = 'Helper Functions';
            app.SaveSettingsButton = uibutton(app.HelperFunctionsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.ConvertHumanAnnotationsButton = uibutton(app.HelperFunctionsPanel, 'push');
            app.ConvertHumanAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertHumanAnnotationsButtonPushed, true);
            app.ConvertHumanAnnotationsButton.Text = 'Convert Human Annotations';
            app.OpenClassifierFolderButton = uibutton(app.HelperFunctionsPanel, 'push');
            app.OpenClassifierFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenClassifierFolderButtonPushed, true);
            app.OpenClassifierFolderButton.Text = 'Open Classifier Folder';
            app.StartDataAnalysisLabel = uilabel(app.GridLayout);
            app.StartDataAnalysisLabel.Text = 'Start Data Analysis';
            app.DataPrepOptionsLabel = uilabel(app.GridLayout);
            app.DataPrepOptionsLabel.Text = 'Data Prep Options';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.HampelspanEditFieldLabel = uilabel(app.GridLayout);
            app.HampelspanEditFieldLabel.Text = 'Hampel span:';
            app.HampelspanEditField = uieditfield(app.GridLayout, 'numeric');
            app.HampelspanEditField.ValueChangedFcn = createCallbackFcn(app, @HampelspanEditFieldValueChanged, true);
            app.HampelspanEditField.Value = 7;
            app.DataPrepCheckBox = uicheckbox(app.GridLayout);
            app.DataPrepCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataPrepCheckBoxValueChanged, true);
            app.DataPrepCheckBox.Text = 'Data Prep';
            app.DataPrepCheckBox.Value = true;
            app.ModulestoRunLabel = uilabel(app.GridLayout);
            app.ModulestoRunLabel.Text = 'Modules to Run';
            app.BehaviorAnalysisCheckBox = uicheckbox(app.GridLayout);
            app.BehaviorAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @BehaviorAnalysisCheckBoxValueChanged, true);
            app.BehaviorAnalysisCheckBox.Text = 'Behavior Analysis';
            app.BehaviorAnalysisCheckBox.Value = true;
            app.ParallelProcessingLabel = uilabel(app.GridLayout);
            app.ParallelProcessingLabel.Text = 'Parallel Processing';
            app.UseGPUSwitchLabel = uilabel(app.GridLayout);
            app.UseGPUSwitchLabel.Text = 'Use GPU';
            app.UseGPUSwitch = uiswitch(app.GridLayout, 'slider');
            app.UseGPUSwitch.ItemsData = [false true];
            app.UseGPUSwitch.ValueChangedFcn = createCallbackFcn(app, @UseGPUSwitchValueChanged, true);
            app.UseGPUSwitch.Value = false;
            app.TrackingFileTypeLabel = uilabel(app.GridLayout);
            app.TrackingFileTypeLabel.Text = 'Tracking File Type';
            app.TrackingFileTypeButton = uidropdown(app.GridLayout);
            app.TrackingFileTypeButton.Items = {'DLC (csv)', 'DLC (h5)', 'SLEAP (h5)'};
            app.TrackingFileTypeButton.ItemsData = {'dlc_csv', 'dlc_h5', 'sleap_h5'};
            app.TrackingFileTypeButton.ValueChangedFcn = createCallbackFcn(app, @TrackingFileTypeButtonValueChanged, true);
            app.TrackingFileTypeButton.Value = 'dlc_csv';
            app.BehaviorDEPOTv16Label = uilabel(app.UIFigure);
            app.BehaviorDEPOTv16Label.Text = 'BehaviorDEPOT v1.6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorDEPOT_GUI_v1_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end