classdef BehDep_GUI_v051 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SinglesessionButton            matlab.ui.control.RadioButton
        BatchprocessButton             matlab.ui.control.RadioButton
        viewspatiotemporaltrajectoryCheckBox  matlab.ui.control.CheckBox
        viewspatialtrajectoryCheckBox  matlab.ui.control.CheckBox
        viewbehaviorlocationsCheckBox  matlab.ui.control.CheckBox
        PlottingpreferencesLabel       matlab.ui.control.Label
        ApplyframelockedeventfiltersCheckBox  matlab.ui.control.CheckBox
        Setto0ifnoROIsLabel            matlab.ui.control.Label
        WallRearingCheckBox            matlab.ui.control.CheckBox
        SelectwhichclassifierstodoLabel  matlab.ui.control.Label
        FreezingCheckBox               matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        MovingCheckBox                 matlab.ui.control.CheckBox
        BehaviorDEPOTv051Label         matlab.ui.control.Label
        EditParamsButton_Freezing      matlab.ui.control.Button
        EditParamsButton_Walking       matlab.ui.control.Button
        SetdistancecalibrationLabel    matlab.ui.control.Label
        pixels1cmEditFieldLabel        matlab.ui.control.Label
        pixels1cmEditField             matlab.ui.control.NumericEditField
        egtonetimeslightdeliveryetcLabel  matlab.ui.control.Label
        ROIEditField                   matlab.ui.control.NumericEditField
        ROISLabel                      matlab.ui.control.Label
        SelectaModuletoLoadPanel       matlab.ui.container.Panel
        InterRaterButton               matlab.ui.control.Button
        ValidationButton               matlab.ui.control.Button
        DataExplorationButton          matlab.ui.control.Button
        ClassifierOptimizationButton   matlab.ui.control.Button
        SavesettingsButton             matlab.ui.control.Button
        LoadsettingsandStartButton     matlab.ui.control.Button
    end

   
    properties (Access = private)
        P = struct; % Struct to hold parameter settings
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.P.do_ROI = false;
                app.P.number_ROIs = 0;
                app.P.do_freezing_classifier = false;
                app.P.do_wallrearing_classifier = false;
                app.P.do_movement_classifier = false;
                app.P.do_events = false;
                app.P.viewSpatialTrajectory = false;
                app.P.viewSpatioTemporalTrajectory = false;
                app.P.viewBehaviorLocations = false;
                app.P.freezing_minDuration = 0.90; % (seconds) set minimum duration of freezing events to include
                app.P.freezing_velocityThreshold = 0.59; % (cm per second) set velocity maximum for freezing
                app.P.freezing_angleThreshold = 15; % (degrees per second) set angular movement max for freezing
                app.P.px2cm = 23;  % pixels per centimeter
                app.P.freezing_sensitivityGain = 1;
                app.P.batch = 0;  % true if batch process; false if not
                app.P.moving_minSpeed = 4;
                
                app.P.tracking_fileType = 'csv';  % currently only csv is supported. cant figure out h5 structure
        end

        % Callback function
        function ROIsEditField2ValueChanged(app, event)
          
        end

        % Value changed function: FreezingCheckBox
        function FreezingCheckBoxValueChanged(app, event)
            value = app.FreezingCheckBox.Value;
            app.P.do_freezing_classifier = value;
        end

        % Value changed function: WallRearingCheckBox
        function WallRearingCheckBoxValueChanged(app, event)
            value = app.WallRearingCheckBox.Value;
            app.P.do_wallrearing_classifier = value;
        end

        % Value changed function: 
        % ApplyframelockedeventfiltersCheckBox
        function ApplyframelockedeventfiltersCheckBoxValueChanged(app, event)
            value = app.ApplyframelockedeventfiltersCheckBox.Value;
            app.P.do_events = value;
        end

        % Callback function
        function VideofiletypeDropDownValueChanged(app, event)
            value = app.VideofiletypeDropDown.Value;
            app.P.video_type = value;
        end

        % Callback function
        function TrackingfiletypeDropDownValueChanged(app, event)
            value = app.TrackingfiletypeDropDown.Value;
            app.P.tracking_fileType = value;
        end

        % Value changed function: viewspatialtrajectoryCheckBox
        function viewspatialtrajectoryCheckBoxValueChanged(app, event)
            value = app.viewspatialtrajectoryCheckBox.Value;
            app.P.viewSpatialTrajectory = value;
        end

        % Value changed function: 
        % viewspatiotemporaltrajectoryCheckBox
        function viewspatiotemporaltrajectoryCheckBoxValueChanged(app, event)
            value = app.viewspatiotemporaltrajectoryCheckBox.Value;
            app.P.viewSpatioTemporalTrajectory = value;
        end

        % Value changed function: viewbehaviorlocationsCheckBox
        function viewbehaviorlocationsCheckBoxValueChanged(app, event)
            value = app.viewbehaviorlocationsCheckBox.Value;
            app.P.viewBehaviorLocations = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.P.batch
                BehDepo_mainscript(app.P)
            else
                BehDepo_mainscript_single(app.P)
            end
        end

        % Button pushed function: EditParamsButton_Freezing
        function EditParamsButton_FreezingPushed(app, event)
            prompt = {'Enter minimum freezing duration (sec):','Enter max velocity threshold (cm/sec):','Enter max angular movement threshold (deg/sec):', 'Adjust sensitivity on jitter freezing detector threshold (2 = more sensitive; 0.5 = less sensitive):'};
            dlgtitle = 'Input';
            dims = [1 50];
            definput = {num2str(app.P.freezing_minDuration),num2str(app.P.freezing_velocityThreshold),num2str(app.P.freezing_angleThreshold), num2str(app.P.freezing_sensitivityGain)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
               app.P.freezing_minDuration = str2double(answer{1}); % (seconds) set minimum duration of freezing events to include
                app.P.freezing_velocityThreshold = str2double(answer{2}); % (pixels per frame) set velocity maximum for freezing
                app.P.freezing_angleThreshold = str2double(answer{3}); % (degrees) set angular movement max for freezing
                app.P.freezing_sensitivityGain = str2double(answer{4}); % adjust sensitivity on freezing detector
            end
        end

        % Value changed function: pixels1cmEditField
        function pixels1cmEditFieldValueChanged(app, event)
            value = app.pixels1cmEditField.Value;
            app.P.px2cm = round(value);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.BatchprocessButton.Value == 1
                app.P.batch = 1;
            else
                app.P.batch = 0;
            end
        end

        % Value changed function: ROIEditField
        function ROIEditFieldValueChanged(app, event)
            value = app.ROIEditField.Value;
            if value == 0
                app.P.do_ROI = false;
                app.P.number_ROIs = 0;
            else
                app.P.do_ROI = true;
                app.P.number_ROIs = value;
                if app.P.number_ROIs == 0
                    app.P.do_roi = false;
                end
            end
        end

        % Button pushed function: EditParamsButton_Walking
        function EditParamsButton_WalkingPushed(app, event)
            prompt = {'Enter minimum movement speed (cm/sec):'};
            dlgtitle = 'Input';
            dims = [1 50];
            definput = {num2str(app.P.moving_minSpeed)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
               app.P.moving_minSpeed = str2double(answer{1}); % (cm/sec) 
            end
        end

        % Value changed function: MovingCheckBox
        function MovingCheckBoxValueChanged(app, event)
            value = app.MovingCheckBox.Value;
            app.P.do_movement_classifier = value;
        end

        % Button pushed function: InterRaterButton
        function InterRaterButtonPushed(app, event)
            launch_IR_module();
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            gui_settings = app.P;
            savedir = uigetdir('','Select directory to save file');
            save('BehDepot_settings.mat', "gui_settings");
            msgbox(['Settings saved in ' savedir]);
        end

        % Button pushed function: LoadsettingsandStartButton
        function LoadsettingsandStartButtonPushed(app, event)
            [f, p] = uigetfile('Select BehaviorDEPOT settings file');
            tmp = load([p f]);
            app.P = tmp.gui_settings;
            
            if app.P.batch
                BehDepo_mainscript(app.P)
            else
                BehDepo_mainscript_single(app.P)
            end
        end

        % Button pushed function: ValidationButton
        function ValidationButtonPushed(app, event)
            validation_module();
        end

        % Button pushed function: DataExplorationButton
        function DataExplorationButtonPushed(app, event)
            data_exploration_module()
        end

        % Button pushed function: ClassifierOptimizationButton
        function ClassifierOptimizationButtonPushed(app, event)
            classifier_optimization_module()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SinglesessionButton = uiradiobutton(app.ButtonGroup);
            app.SinglesessionButton.Text = 'Single session';
            app.SinglesessionButton.Value = true;
            app.BatchprocessButton = uiradiobutton(app.ButtonGroup);
            app.BatchprocessButton.Text = 'Batch process';
            app.viewspatiotemporaltrajectoryCheckBox = uicheckbox(app.GridLayout);
            app.viewspatiotemporaltrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @viewspatiotemporaltrajectoryCheckBoxValueChanged, true);
            app.viewspatiotemporaltrajectoryCheckBox.Text = 'view spatiotemporal trajectory';
            app.viewspatialtrajectoryCheckBox = uicheckbox(app.GridLayout);
            app.viewspatialtrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @viewspatialtrajectoryCheckBoxValueChanged, true);
            app.viewspatialtrajectoryCheckBox.Text = 'view spatial trajectory';
            app.viewbehaviorlocationsCheckBox = uicheckbox(app.GridLayout);
            app.viewbehaviorlocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @viewbehaviorlocationsCheckBoxValueChanged, true);
            app.viewbehaviorlocationsCheckBox.Text = 'view behavior locations';
            app.PlottingpreferencesLabel = uilabel(app.GridLayout);
            app.PlottingpreferencesLabel.Text = 'Plotting preferences';
            app.ApplyframelockedeventfiltersCheckBox = uicheckbox(app.GridLayout);
            app.ApplyframelockedeventfiltersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyframelockedeventfiltersCheckBoxValueChanged, true);
            app.ApplyframelockedeventfiltersCheckBox.Text = 'Apply frame-locked event filter(s)?';
            app.Setto0ifnoROIsLabel = uilabel(app.GridLayout);
            app.Setto0ifnoROIsLabel.Text = '(Set to 0 if no ROIs)';
            app.WallRearingCheckBox = uicheckbox(app.GridLayout);
            app.WallRearingCheckBox.ValueChangedFcn = createCallbackFcn(app, @WallRearingCheckBoxValueChanged, true);
            app.WallRearingCheckBox.Text = 'Wall Rearing';
            app.SelectwhichclassifierstodoLabel = uilabel(app.GridLayout);
            app.SelectwhichclassifierstodoLabel.Text = 'Select which classifiers to do';
            app.FreezingCheckBox = uicheckbox(app.GridLayout);
            app.FreezingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreezingCheckBoxValueChanged, true);
            app.FreezingCheckBox.Text = 'Freezing';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MovingCheckBox = uicheckbox(app.GridLayout);
            app.MovingCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingCheckBoxValueChanged, true);
            app.MovingCheckBox.Text = 'Moving';
            app.BehaviorDEPOTv051Label = uilabel(app.GridLayout);
            app.BehaviorDEPOTv051Label.Text = 'BehaviorDEPOT v051';
            app.EditParamsButton_Freezing = uibutton(app.GridLayout, 'push');
            app.EditParamsButton_Freezing.ButtonPushedFcn = createCallbackFcn(app, @EditParamsButton_FreezingPushed, true);
            app.EditParamsButton_Freezing.Text = 'Edit Params';
            app.EditParamsButton_Walking = uibutton(app.GridLayout, 'push');
            app.EditParamsButton_Walking.ButtonPushedFcn = createCallbackFcn(app, @EditParamsButton_WalkingPushed, true);
            app.EditParamsButton_Walking.Text = 'Edit Params';
            app.SetdistancecalibrationLabel = uilabel(app.GridLayout);
            app.SetdistancecalibrationLabel.Text = 'Set distance calibration';
            app.pixels1cmEditFieldLabel = uilabel(app.GridLayout);
            app.pixels1cmEditFieldLabel.Text = 'pixels = 1 cm      ';
            app.pixels1cmEditField = uieditfield(app.GridLayout, 'numeric');
            app.pixels1cmEditField.ValueChangedFcn = createCallbackFcn(app, @pixels1cmEditFieldValueChanged, true);
            app.egtonetimeslightdeliveryetcLabel = uilabel(app.GridLayout);
            app.egtonetimeslightdeliveryetcLabel.Text = '(eg tone times, light delivery, etc)';
            app.ROIEditField = uieditfield(app.GridLayout, 'numeric');
            app.ROIEditField.ValueChangedFcn = createCallbackFcn(app, @ROIEditFieldValueChanged, true);
            app.ROISLabel = uilabel(app.GridLayout);
            app.ROISLabel.Text = '# ROIS';
            app.SelectaModuletoLoadPanel = uipanel(app.GridLayout);
            app.SelectaModuletoLoadPanel.Title = 'Select a Module to Load:';
            app.InterRaterButton = uibutton(app.SelectaModuletoLoadPanel, 'push');
            app.InterRaterButton.ButtonPushedFcn = createCallbackFcn(app, @InterRaterButtonPushed, true);
            app.InterRaterButton.Text = 'Inter-Rater';
            app.ValidationButton = uibutton(app.SelectaModuletoLoadPanel, 'push');
            app.ValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationButtonPushed, true);
            app.ValidationButton.Text = 'Validation';
            app.DataExplorationButton = uibutton(app.SelectaModuletoLoadPanel, 'push');
            app.DataExplorationButton.ButtonPushedFcn = createCallbackFcn(app, @DataExplorationButtonPushed, true);
            app.DataExplorationButton.Text = {'Data'; 'Exploration'};
            app.ClassifierOptimizationButton = uibutton(app.SelectaModuletoLoadPanel, 'push');
            app.ClassifierOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifierOptimizationButtonPushed, true);
            app.ClassifierOptimizationButton.Text = {'Classifier'; 'Optimization'};
            app.SavesettingsButton = uibutton(app.GridLayout, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsandStartButton = uibutton(app.GridLayout, 'push');
            app.LoadsettingsandStartButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsandStartButtonPushed, true);
            app.LoadsettingsandStartButton.Text = 'Load settings and Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehDep_GUI_v051

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end